classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EditField_5  matlab.ui.control.EditField
        Label_5      matlab.ui.control.Label
        EditField_4  matlab.ui.control.EditField
        Label_4      matlab.ui.control.Label
        EditField_3  matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        Button       matlab.ui.control.Button
        Label_6      matlab.ui.control.Label
        Image        matlab.ui.control.Image
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        ContextMenu  matlab.ui.container.ContextMenu
        Menu         matlab.ui.container.Menu
        Menu_2       matlab.ui.container.Menu
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 更改 app 左上角显示名字
            app.UIFigure.Name = '两种群相互竞争模型';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.EditField.Value = '邓睿';
            app.EditField_3.Value = '数学与应用数学（统计学）';
            app.EditField_5.Value = '202130320342';
            app.EditField_4.Value = string(datetime('now'));
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            visualize;
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '姓名';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '图片.jpg');
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {'两种群相互竞争模型'; ''};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '菜单';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '班级';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '完成时间';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '学号';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '进入';
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '退出';
            % Assign app.ContextMenu
            app.Button.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end