classdef visualize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        outfunction         matlab.ui.container.Tab
        Panel_2             matlab.ui.container.Panel
        UIAxes2             matlab.ui.control.UIAxes
        Panel               matlab.ui.container.Panel
        run2_4              matlab.ui.control.Button
        run2_3              matlab.ui.control.Button
        run2_2              matlab.ui.control.Button
        dT                  matlab.ui.control.NumericEditField
        Label_3             matlab.ui.control.Label
        TF                  matlab.ui.control.NumericEditField
        Label_2             matlab.ui.control.Label
        T0                  matlab.ui.control.NumericEditField
        EditField_4Label    matlab.ui.control.Label
        n20                 matlab.ui.control.NumericEditField
        yEditField_10Label  matlab.ui.control.Label
        n10                 matlab.ui.control.NumericEditField
        xLabel_3            matlab.ui.control.Label
        S2                  matlab.ui.control.NumericEditField
        yEditField_9Label   matlab.ui.control.Label
        S1                  matlab.ui.control.NumericEditField
        xLabel_2            matlab.ui.control.Label
        k2                  matlab.ui.control.NumericEditField
        yEditField_8Label   matlab.ui.control.Label
        k1                  matlab.ui.control.NumericEditField
        xLabel              matlab.ui.control.Label
        R2                  matlab.ui.control.NumericEditField
        yEditField_7Label   matlab.ui.control.Label
        run2                matlab.ui.control.Button
        R1                  matlab.ui.control.NumericEditField
        xEditFieldLabel     matlab.ui.control.Label
        Label               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: run2
        function run2ButtonPushed(app, event)
            r1 = app.R1.Value;
            r2 = app.R2.Value;
            K1 = app.k1.Value;
            K2 = app.k2.Value;
            s1 = app.S1.Value;
            s2 = app.S2.Value;
            N10 = app.n10.Value;
            N20 = app.n20.Value;
            t0 = app.T0.Value;
            tf = app.TF.Value;
            dt = app.dT.Value;
            population_competition(app,r1, r2, K1, K2, s1, s2, N10, N20, t0, tf, dt);
           
        end

        % Button pushed function: run2_2
        function run2_2ButtonPushed(app, event)
            fig = figure('visible', 'off');
            copyobj(app.UIAxes2, fig);
            filter = {'*.fig';'*.png';'*.tif';'*.pdf';'*.eps';'*.jpg'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportgraphics(fig,[filepath filename]);
            end
        end

        % Button pushed function: run2_3
        function run2_3ButtonPushed(app, event)
            cla(app.UIAxes2);
        end

        % Button pushed function: run2_4
        function run2_4ButtonPushed(app, event)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'两种群相互竞争模型'; ''};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.outfunction = uitab(app.TabGroup);
            app.outfunction.Title = '数量变化趋势';
            app.Panel = uipanel(app.outfunction);
            app.Panel.Title = '操作区域';
            app.xEditFieldLabel = uilabel(app.Panel);
            app.xEditFieldLabel.Text = 'x种群增长率';
            app.R1 = uieditfield(app.Panel, 'numeric');
            app.R1.Value = 0.5;
            app.run2 = uibutton(app.Panel, 'push');
            app.run2.ButtonPushedFcn = createCallbackFcn(app, @run2ButtonPushed, true);
            app.run2.Text = '运行';
            app.yEditField_7Label = uilabel(app.Panel);
            app.yEditField_7Label.Text = 'y种群增长率';
            app.R2 = uieditfield(app.Panel, 'numeric');
            app.R2.Value = 0.5;
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x种群容纳量';
            app.k1 = uieditfield(app.Panel, 'numeric');
            app.k1.Value = 100;
            app.yEditField_8Label = uilabel(app.Panel);
            app.yEditField_8Label.Text = 'y种群容纳量';
            app.k2 = uieditfield(app.Panel, 'numeric');
            app.k2.Value = 100;
            app.xLabel_2 = uilabel(app.Panel);
            app.xLabel_2.Text = 'x种群个体生存能力';
            app.S1 = uieditfield(app.Panel, 'numeric');
            app.S1.Value = 0.5;
            app.yEditField_9Label = uilabel(app.Panel);
            app.yEditField_9Label.Text = 'y种群个体生存能力';
            app.S2 = uieditfield(app.Panel, 'numeric');
            app.S2.Value = 2;
            app.xLabel_3 = uilabel(app.Panel);
            app.xLabel_3.Text = 'x种群初始数量';
            app.n10 = uieditfield(app.Panel, 'numeric');
            app.n10.Value = 10;
            app.yEditField_10Label = uilabel(app.Panel);
            app.yEditField_10Label.Text = 'y种群初始数量';
            app.n20 = uieditfield(app.Panel, 'numeric');
            app.n20.Value = 10;
            app.EditField_4Label = uilabel(app.Panel);
            app.EditField_4Label.Text = '初始时间';
            app.T0 = uieditfield(app.Panel, 'numeric');
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '终止时间';
            app.TF = uieditfield(app.Panel, 'numeric');
            app.TF.Value = 20;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '时间步长';
            app.dT = uieditfield(app.Panel, 'numeric');
            app.dT.Value = 0.01;
            app.run2_2 = uibutton(app.Panel, 'push');
            app.run2_2.ButtonPushedFcn = createCallbackFcn(app, @run2_2ButtonPushed, true);
            app.run2_2.Text = '保存';
            app.run2_3 = uibutton(app.Panel, 'push');
            app.run2_3.ButtonPushedFcn = createCallbackFcn(app, @run2_3ButtonPushed, true);
            app.run2_3.Text = '清除';
            app.run2_4 = uibutton(app.Panel, 'push');
            app.run2_4.ButtonPushedFcn = createCallbackFcn(app, @run2_4ButtonPushed, true);
            app.run2_4.Text = '退出';
            app.Panel_2 = uipanel(app.outfunction);
            app.Panel_2.Title = '结果显示区域';
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, '两种群数量变化趋势')
            xlabel(app.UIAxes2, '时间')
            ylabel(app.UIAxes2, '数量')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end